classdef dialog_box_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        txtImageDirectory   matlab.ui.control.Label
        txtOutputDirectory  matlab.ui.control.Label
        Cy3Threshold        matlab.ui.control.EditField
        FITCthreshold       matlab.ui.control.EditField
        imageDirectory      matlab.ui.control.Button
        outputDirectory     matlab.ui.control.Button
        run                 matlab.ui.control.Button
        text5               matlab.ui.control.Label
        text6               matlab.ui.control.Label
        text7               matlab.ui.control.Label
        text8               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function dialog_box_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to dialog_box (see VARARGIN)
            
            % Choose default command line output for dialog_box
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: imageDirectory
        function imageDirectory_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to imageDirectory (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imgdir = uigetdir();
            set(handles.txtImageDirectory, 'string', imgdir);
        end

        % Button pushed function: outputDirectory
        function outputDirectory_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to outputDirectory (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            outdir = uigetdir();
            set(handles.txtOutputDirectory, 'string', outdir);
        end

        % Button pushed function: run
        function run_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to run (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            imagefolder = get(handles.txtImageDirectory, 'string');
            outfolder = get(handles.txtOutputDirectory, 'string');
            Cy3threshold = str2double(get(handles.Cy3Threshold, 'string'));
            FITCthreshold = str2double(get(handles.FITCthreshold, 'string'));
            if strcmp(imagefolder, 'Select Image Directory') || strcmp(outfolder, 'Select Data Output Directory') || isnan(Cy3threshold) || isnan(FITCthreshold)
                errordlg('All fields are required');
            else
                cross_section_staining_analysis(imagefolder, Cy3threshold, FITCthreshold, outfolder);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'dialog_box';
            app.figure1.Tag = 'figure1';
            app.txtImageDirectory = uilabel(app.figure1);
            app.txtImageDirectory.Tag = 'txtImageDirectory';
            app.txtImageDirectory.Text = 'Select Image Directory';
            app.txtOutputDirectory = uilabel(app.figure1);
            app.txtOutputDirectory.Tag = 'txtOutputDirectory';
            app.txtOutputDirectory.Text = 'Select Data Output Directory';
            app.Cy3Threshold = uieditfield(app.figure1, 'text');
            app.Cy3Threshold.Tag = 'Cy3Threshold';
            app.FITCthreshold = uieditfield(app.figure1, 'text');
            app.FITCthreshold.Tag = 'FITCthreshold';
            app.imageDirectory = uibutton(app.figure1, 'push');
            app.imageDirectory.ButtonPushedFcn = createCallbackFcn(app, @imageDirectory_Callback, true);
            app.imageDirectory.Tag = 'imageDirectory';
            app.imageDirectory.Text = 'Browse';
            app.outputDirectory = uibutton(app.figure1, 'push');
            app.outputDirectory.ButtonPushedFcn = createCallbackFcn(app, @outputDirectory_Callback, true);
            app.outputDirectory.Tag = 'outputDirectory';
            app.outputDirectory.Text = 'Browse';
            app.run = uibutton(app.figure1, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @run_Callback, true);
            app.run.Tag = 'run';
            app.run.Text = 'RUN';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Cy3 Threshold';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'FITC Threshold';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Data Output Directory';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Image Directory';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialog_box_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)dialog_box_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end